classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PredictionandDetectionofEpilepticSeizuresUIFigure  matlab.ui.Figure
        EncoderDropDownLabel    matlab.ui.control.Label
        EncoderDropDown         matlab.ui.control.DropDown
        ExecuteButton           matlab.ui.control.Button
        DatasetDropDownLabel    matlab.ui.control.Label
        DatasetDropDown         matlab.ui.control.DropDown
        NetworkDropDownLabel    matlab.ui.control.Label
        NetworkDropDown         matlab.ui.control.DropDown
        TrainEncoderandNeuralNetworkCheckBox  matlab.ui.control.CheckBox
        ResultsTextAreaLabel    matlab.ui.control.Label
        ResultsTextArea         matlab.ui.control.TextArea
        ModeDropDownLabel       matlab.ui.control.Label
        ModeDropDown            matlab.ui.control.DropDown
        PostProcessingCheckBox  matlab.ui.control.CheckBox
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: NetworkDropDown
        function NetworkDropDownValueChanged(app, event)
            value = app.NetworkDropDown.Value;
            app.EncoderDropDown.Enable = value ~= "cnn";
            if(app.EncoderDropDown.Enable == false)
                app.EncoderDropDown.Value = '[]';
            end
        end

        % Button pushed function: ExecuteButton
        function ExecuteButtonPushed(app, event)
            app.ExecuteButton.Enable = false;
            app.ResultsTextArea.Value = "Computing...";
            dataset = app.DatasetDropDown.Value;
            network = app.NetworkDropDown.Value;
            encoding = str2num(app.EncoderDropDown.Value);
            train = app.TrainEncoderandNeuralNetworkCheckBox.Value;
            class = str2num(app.ModeDropDown.Value);
            pp = app.PostProcessingCheckBox.Value;
            try
                [SP_train, SS_train, A_train, SP_test, SS_test, A_test] = ...
                main(dataset, network, encoding, train, class, pp);
            
                if isempty(encoding)
                    encoding = "None";
                else
                    encoding = regexprep(num2str(encoding), " +", "-");
                end
                
                app.ResultsTextArea.Value = sprintf(...
                    "Pipeline Summary:\n" + ...
                    "    - Patient:  " + dataset + "\n" + ...
                    "    - Network:  " + network + "\n" + ...
                    "    - Encoding:  " + encoding + "\n" + ...
                    "    - Train:  " + train + "\n" + ...
                    "    - Mode:  " + class + "                             (Predict=2, Detect=3)\n" + ...
                    "    - Post Processing: " + pp + "        (Ws=10, Threshold=5)\n\n" + ...
                    "Results:\n" + ...
                    "    - Train Set:\n" + ...
                    "          -> Specificity:  " + SP_train + "\n" + ...
                    "          -> Sensibility:  " + SS_train + "\n" + ...
                    "          -> Accuracy:  " + A_train + "\n\n" + ...
                    "    - Test Set:\n" + ...
                    "          -> Specificity:  " + SP_test + "\n" + ...
                    "          -> Sensibility:  " + SS_test + "\n" + ...
                    "          -> Accuracy:  " + A_test + "\n");
            catch ME
                disp(ME);
                app.ResultsTextArea.Value = sprintf("The pipeline you chose is not trained.\nPlease train this pipeline before testing it.\n\nDetails:\n    -" + ME.message);
            end
            app.ExecuteButton.Enable = true;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PredictionandDetectionofEpilepticSeizuresUIFigure = uifigure('Visible', 'off');
            app.PredictionandDetectionofEpilepticSeizuresUIFigure.Name = 'Prediction and Detection of Epileptic Seizures';
            app.EncoderDropDownLabel = uilabel(app.PredictionandDetectionofEpilepticSeizuresUIFigure);
            app.EncoderDropDownLabel.Text = 'Encoder';
            app.EncoderDropDown = uidropdown(app.PredictionandDetectionofEpilepticSeizuresUIFigure);
            app.EncoderDropDown.Items = {'None', '3', '10', '15', '15-10', '10-3'};
            app.EncoderDropDown.ItemsData = {'[]', '[3]', '[10]', '[15]', '[15,10]', '[10,3]'};
            app.EncoderDropDown.Value = '[]';
            app.ExecuteButton = uibutton(app.PredictionandDetectionofEpilepticSeizuresUIFigure, 'push');
            app.ExecuteButton.ButtonPushedFcn = createCallbackFcn(app, @ExecuteButtonPushed, true);
            app.ExecuteButton.Text = 'Execute';
            app.DatasetDropDownLabel = uilabel(app.PredictionandDetectionofEpilepticSeizuresUIFigure);
            app.DatasetDropDownLabel.Text = 'Dataset';
            app.DatasetDropDown = uidropdown(app.PredictionandDetectionofEpilepticSeizuresUIFigure);
            app.DatasetDropDown.Items = {'54802', '112502'};
            app.DatasetDropDown.ItemsData = {'54802.mat', '112502.mat'};
            app.DatasetDropDown.Value = '54802.mat';
            app.NetworkDropDownLabel = uilabel(app.PredictionandDetectionofEpilepticSeizuresUIFigure);
            app.NetworkDropDownLabel.Text = 'Network';
            app.NetworkDropDown = uidropdown(app.PredictionandDetectionofEpilepticSeizuresUIFigure);
            app.NetworkDropDown.Items = {'Feed Forward', 'Time Delay', 'Convolutional', 'Long Short Term Memory'};
            app.NetworkDropDown.ItemsData = {'feedforwardnet', 'layrecnet', 'cnn', 'lstm'};
            app.NetworkDropDown.ValueChangedFcn = createCallbackFcn(app, @NetworkDropDownValueChanged, true);
            app.NetworkDropDown.Value = 'feedforwardnet';
            app.TrainEncoderandNeuralNetworkCheckBox = uicheckbox(app.PredictionandDetectionofEpilepticSeizuresUIFigure);
            app.TrainEncoderandNeuralNetworkCheckBox.Text = 'Train Encoder and Neural Network';
            app.ResultsTextAreaLabel = uilabel(app.PredictionandDetectionofEpilepticSeizuresUIFigure);
            app.ResultsTextAreaLabel.Text = 'Results';
            app.ResultsTextArea = uitextarea(app.PredictionandDetectionofEpilepticSeizuresUIFigure);
            app.ResultsTextArea.Editable = 'off';
            app.ModeDropDownLabel = uilabel(app.PredictionandDetectionofEpilepticSeizuresUIFigure);
            app.ModeDropDownLabel.Text = 'Mode';
            app.ModeDropDown = uidropdown(app.PredictionandDetectionofEpilepticSeizuresUIFigure);
            app.ModeDropDown.Items = {'Predict', 'Detect'};
            app.ModeDropDown.ItemsData = {'2', '3'};
            app.ModeDropDown.Value = '2';
            app.PostProcessingCheckBox = uicheckbox(app.PredictionandDetectionofEpilepticSeizuresUIFigure);
            app.PostProcessingCheckBox.Text = 'Post Processing';
            % Show the figure after all components are created
            app.PredictionandDetectionofEpilepticSeizuresUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PredictionandDetectionofEpilepticSeizuresUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PredictionandDetectionofEpilepticSeizuresUIFigure)
        end
    end
end